classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        AP02AtividadeComputacionalLabel  matlab.ui.control.Label
        HTML                           matlab.ui.control.HTML
        TabGroup                       matlab.ui.container.TabGroup
        AlternativaaTab                matlab.ui.container.Tab
        GridLayout2                    matlab.ui.container.GridLayout
        aCalculeaDFTeFFTsdexnusando32amostrasdosinalLabel  matlab.ui.control.Label
        alternativaASelectButtons      matlab.ui.container.ButtonGroup
        TransformadaDiscretadeFourierDFTButton  matlab.ui.control.RadioButton
        TransformadaRpidadeFourierFFTDizimaonotempoButton  matlab.ui.control.RadioButton
        TransformadaRpidadeFourierFFTDizimaonafrequnciaButton  matlab.ui.control.RadioButton
        alternativaACoeficientesPanel  matlab.ui.container.Panel
        UITableA                       matlab.ui.control.Table
        GrficocomXknormalizadoPanel    matlab.ui.container.Panel
        UIAxesA                        matlab.ui.control.UIAxes
        AlternativabTab                matlab.ui.container.Tab
        GridLayout2_2                  matlab.ui.container.GridLayout
        bCalculeaDFTeFFTsdexnusando32amostrasdosinalLabel  matlab.ui.control.Label
        alternativaBSelectButtons      matlab.ui.container.ButtonGroup
        TransformadaDiscretadeFourierDFTButton_2  matlab.ui.control.RadioButton
        TransformadaRpidadeFourierFFTDizimaonotempoButton_2  matlab.ui.control.RadioButton
        TransformadaRpidadeFourierFFTDizimaonafrequnciaButton_2  matlab.ui.control.RadioButton
        alternativaACoeficientesPanel_2  matlab.ui.container.Panel
        UITableB                       matlab.ui.control.Table
        GrficocomXknormalizadoPanel_2  matlab.ui.container.Panel
        UIAxesB                        matlab.ui.control.UIAxes
        AlternativacTab                matlab.ui.container.Tab
        GridLayout2_3                  matlab.ui.container.GridLayout
        bCalculeaDFTeFFTsdexnusando32amostrasdosinalLabel_2  matlab.ui.control.Label
        alternativaCSelectButtons      matlab.ui.container.ButtonGroup
        TransformadaDiscretadeFourierDFTButton_3  matlab.ui.control.RadioButton
        TransformadaRpidadeFourierFFTDizimaonotempoButton_3  matlab.ui.control.RadioButton
        TransformadaRpidadeFourierFFTDizimaonafrequnciaButton_3  matlab.ui.control.RadioButton
        alternativaACoeficientesPanel_3  matlab.ui.container.Panel
        TabGroup2                      matlab.ui.container.TabGroup
        XamostNpontosTab_1             matlab.ui.container.Tab
        UITableC_1                     matlab.ui.control.Table
        XamostNpontosTab_2             matlab.ui.container.Tab
        UITableC_2                     matlab.ui.control.Table
        GrficocomXknormalizadoPanel_3  matlab.ui.container.Panel
        TabGroup3                      matlab.ui.container.TabGroup
        XamostrasNpontosTab_1          matlab.ui.container.Tab
        UIAxesC_1                      matlab.ui.control.UIAxes
        XamostrasNpontosTab_2          matlab.ui.container.Tab
        UIAxesC_2                      matlab.ui.control.UIAxes
        SelecaqntddeamostrasdesejadasPanel  matlab.ui.container.Panel
        Slider                         matlab.ui.control.Slider
        AlternativadTab                matlab.ui.container.Tab
        GridLayout2_4                  matlab.ui.container.GridLayout
        bCalculeaDFTeFFTsdexnusando32amostrasdosinalLabel_3  matlab.ui.control.Label
        UITableD                       matlab.ui.control.Table
        AlternativaeTab                matlab.ui.container.Tab
        GridLayout5                    matlab.ui.container.GridLayout
        UIAxesX_Original               matlab.ui.control.UIAxes
        UIAxesX_Estimado               matlab.ui.control.UIAxes
        SelecioneaDFTquedesejaaplicaraiDFTeanalisarPanel  matlab.ui.container.Panel
        DropDown                       matlab.ui.control.DropDown
        bCalculeaDFTeFFTsdexnusando32amostrasdosinalLabel_4  matlab.ui.control.Label
    end

    
    properties (Access = private)
        n32 = 0:1:31;                     % Vetor n para um sinal com 32 amostras
        n64 = 0:1:63;                     % Vetor n para um sinal com 64 amostras
        n128 = 0:1:127;                   % Vetor n para um sinal com 128 amostras
        n256 = 0:1:255;                   % Vetor n para um sinal com 256 amostras
        n512 = 0:1:511;                   % Vetor n para um sinal com 512 amostras
        n1024= 0:1:1023;                  % Vetor n para um sinal com 1024 amostras
        Ts = 1/500;                       % Período de amostragem do sinal (em segundos)            
    end
    
    methods (Access = private)
        
        function resp = t(app, n)         % Definição do tempo do amostrado: x[n] é obtido quando t = n*Ts
            resp = n .* app.Ts;
        end
        
        function resp = xc(~, t)
            resp = 1*sin(20*pi*60*t) + ... % Sinal inicial
                   3*sin(2*pi*240*t) + ...
                   4*sin(2*pi*120*t) + ...
                   sin(2*pi*180*t);
        end
        function [numAmostras, nminUsado, nmaxUsado] = getCorrectValue(app, selectedVal)
            if selectedVal == 64
                numAmostras = 64;
                nminUsado = app.n64;
                nmaxUsado = app.n128;
            elseif selectedVal == 128
                numAmostras = 128;
                nminUsado = app.n128;
                nmaxUsado = app.n256;
            elseif selectedVal == 192
                numAmostras = 256;
                nminUsado = app.n256;
                nmaxUsado = app.n512;
            elseif selectedVal == 256
                numAmostras = 512;
                nminUsado = app.n512;
                nmaxUsado = app.n1024;
            end
        end
        
        function setAlternativaA(app)
            selectedButton = app.alternativaASelectButtons.SelectedObject;
            signal = app.xc(app.t(app.n32));
            if selectedButton.Text == "Transformada Discreta de Fourier (DFT)"
                dft_a = my_dft(signal, 32);
                tdata = table(app.n32', dft_a);
                stem(app.UIAxesA, app.n32 - 16, abs(dft_a/16));
            elseif selectedButton.Text == "Transformada Rápida de Fourier (FFT) - Dizimação no tempo"
                fft_time_a = my_fft_time(signal, 32);
                tdata = table(app.n32', fft_time_a.');
                stem(app.UIAxesA, app.n32 - 16, abs(fft_time_a/16));
            else
                fft_freq_b = my_fft_freq(signal, 32);
                tdata = table(app.n32', fft_freq_b.');
                stem(app.UIAxesA, app.n32 - 16, abs(fft_freq_b/16));
            end
            app.UITableA.Data = tdata;
        end
        
        function setAlternativaB(app)
            selectedButton = app.alternativaBSelectButtons.SelectedObject;
            signal = app.xc(app.t(app.n32));
            if selectedButton.Text == "Transformada Discreta de Fourier (DFT)"
                dft_b = my_dft(signal, 64);
                tdata = table(app.n64', dft_b);
                stem(app.UIAxesB, app.n64 - 32, abs(dft_b/32));
            elseif selectedButton.Text == "Transformada Rápida de Fourier (FFT) - Dizimação no tempo"
                fft_time_b = my_fft_time(signal, 64);
                tdata = table(app.n64', fft_time_b.');
                stem(app.UIAxesB, app.n64 - 32, abs(fft_time_b/32));
            else
                fft_freq_b = my_fft_freq(signal, 64);
                tdata = table(app.n64', fft_freq_b.');
                stem(app.UIAxesB, app.n64 - 32, abs(fft_freq_b/32));
            end
            app.UITableB.Data = tdata;
        end
        
        function setAlternativaC(app)
            selectedButton = app.alternativaCSelectButtons.SelectedObject;
            [numAmostras, nminUsado, nmaxUsado] = app.getCorrectValue(app.Slider.Value);
            signal_min = app.xc(app.t(nminUsado));
            signal_max = app.xc(app.t(nmaxUsado));
            if selectedButton.Text == "Transformada Discreta de Fourier (DFT)"
                dft_c_a = my_dft(signal_min, numAmostras);
                dft_c_b = my_dft(signal_max, numAmostras*2);
                tdata1 = table(nminUsado', dft_c_a);
                tdata2 = table(nmaxUsado', dft_c_b);
                stem(app.UIAxesC_1, nminUsado - numAmostras/2, abs(dft_c_a/(numAmostras/2)));
                stem(app.UIAxesC_2, nmaxUsado - numAmostras, abs(dft_c_b/numAmostras));
            elseif selectedButton.Text == "Transformada Rápida de Fourier (FFT) - Dizimação no tempo"
                fft_time_c_a = my_fft_time(signal_min, numAmostras);
                fft_time_c_b = my_fft_time(signal_max, numAmostras*2);
                tdata1 = table(nminUsado', fft_time_c_a.');
                tdata2 = table(nmaxUsado', fft_time_c_b.');
                stem(app.UIAxesC_1, nminUsado - numAmostras/2, abs(fft_time_c_a/(numAmostras/2)));
                stem(app.UIAxesC_2, nmaxUsado - numAmostras, abs(fft_time_c_b/numAmostras));
            else
                fft_freq_c_a = my_fft_freq(signal_min, numAmostras);
                fft_freq_c_b = my_fft_freq(signal_max, numAmostras*2);
                tdata1 = table(nminUsado', fft_freq_c_a.');
                tdata2 = table(nmaxUsado', fft_freq_c_b.');
                stem(app.UIAxesC_1, nminUsado - numAmostras/2, abs(fft_freq_c_a/(numAmostras/2)));
                stem(app.UIAxesC_2, nmaxUsado - numAmostras, abs(fft_freq_c_b/numAmostras));
            end
            app.XamostNpontosTab_1.Title = num2str(numAmostras) + " amost. - " + num2str(numAmostras) + " pontos";
            app.XamostNpontosTab_2.Title = num2str(numAmostras) + " amost. - " + num2str(2*numAmostras) + " pontos";
            app.XamostrasNpontosTab_1.Title = num2str(numAmostras) + " amost. - " + num2str(numAmostras) + " pontos";
            app.XamostrasNpontosTab_2.Title = num2str(numAmostras) + " amost. - " + num2str(2*numAmostras) + " pontos";
            app.UITableC_1.Data = tdata1;
            app.UITableC_2.Data = tdata2;
        end
        
        function setAlternativaD(app)
            N = [32;64;128;256;512;1024];
            SomasDft = [sumOp(32, "DFT");sumOp(64, "DFT");sumOp(128, "DFT");sumOp(256, "DFT");sumOp(512, "DFT");sumOp(1024, "DFT")];
            ProdutosDft = [multOp(32, "DFT");multOp(64, "DFT");multOp(128, "DFT");multOp(256, "DFT");multOp(512, "DFT");multOp(1024, "DFT")];
            SomasFft = [sumOp(32, "FFT-diz");sumOp(64, "FFT-diz");sumOp(128, "FFT-diz");sumOp(256, "FFT-diz");sumOp(512, "FFT-diz");sumOp(1024, "FFT-diz")];
            ProdutosFft = [multOp(32, "FFT-diz");multOp(64, "FFT-diz");multOp(128, "FFT-diz");multOp(256, "FFT-diz");multOp(512, "FFT-diz");multOp(1024, "FFT-diz")];
            tdata = table(N, SomasDft, SomasFft, ProdutosDft, ProdutosFft);
            app.UITableD.Data = tdata;
        end
        
        function setAlternativaE(app)
            if app.DropDown.Value ==     "x[n] com 32 amostras, DFT com 32 pontos"
                app.plotXdeT(app.n32, app.n32, 32, 32);
            elseif app.DropDown.Value == "x[n] com 32 amostras, DFT com 64 pontos"
                app.plotXdeT(app.n32, app.n64, 64, 32);
            elseif app.DropDown.Value == "x[n] com 64 amostras, DFT com 64 pontos"
                app.plotXdeT(app.n64, app.n64, 64, 64);
            elseif app.DropDown.Value == "x[n] com 64 amostras, DFT com 128 pontos"
                app.plotXdeT(app.n64, app.n128, 128, 64);
            elseif app.DropDown.Value == "x[n] com 128 amostras, DFT com 128 pontos"
                app.plotXdeT(app.n128, app.n128, 128, 128);
            elseif app.DropDown.Value == "x[n] com 128 amostras, DFT com 256 pontos"
                app.plotXdeT(app.n128, app.n256, 256, 128);
            elseif app.DropDown.Value == "x[n] com 256 amostras, DFT com 256 pontos"
                app.plotXdeT(app.n256, app.n256, 256, 256);
            elseif app.DropDown.Value == "x[n] com 256 amostras, DFT com 512 pontos"
                app.plotXdeT(app.n256, app.n512, 512, 256);
            elseif app.DropDown.Value == "x[n] com 512 amostras, DFT com 512 pontos"
                app.plotXdeT(app.n512, app.n512, 512, 512);
            elseif app.DropDown.Value == "x[n] com 512 amostras, DFT com 1024 pontos"
                app.plotXdeT(app.n512, app.n1024, 1024, 512);
            end
        end
        function plotXdeT(app, nUsado, nOutroPlot, numAmostrasFFT, numAmostrasSinal)
            signal = app.xc(app.t(nUsado));
            dft_a = my_dft(signal, numAmostrasFFT);
            signal2 = app.xc(0:0.002:0.002*(numAmostrasSinal-1));
            plot(app.UIAxesX_Original, 0:0.002:0.002*(numAmostrasSinal-1), signal2);
            idft_a = my_idft(dft_a, numAmostrasFFT);
            plot(app.UIAxesX_Estimado, 0:0.002:0.002*(numAmostrasFFT-1), idft_a);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.setAlternativaA();  % Alternativa A inicial
            app.setAlternativaB();  % Alternativa B inicial
            app.setAlternativaC();  % Alternativa C inicial
            app.setAlternativaD();  % Alternativa D inicial
            app.setAlternativaE();  % Alternativa E inicial
        end

        % Selection changed function: alternativaASelectButtons
        function alternativaASelectButtonsSelectionChanged(app, event)
           app.setAlternativaA(); 
        end

        % Selection changed function: alternativaBSelectButtons
        function alternativaBSelectButtonsSelectionChanged(app, event)
            app.setAlternativaB();
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            % determine which discrete option the current value is closest to.
            [~, minIdx] = min(abs(value - event.Source.MajorTicks(:)));
            % move the slider to that option
            event.Source.Value = event.Source.MajorTicks(minIdx);
            app.setAlternativaC();
        end

        % Selection changed function: alternativaCSelectButtons
        function alternativaCSelectButtonsSelectionChanged(app, event)
            app.setAlternativaC();
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            app.setAlternativaE();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.AP02AtividadeComputacionalLabel = uilabel(app.GridLayout);
            app.AP02AtividadeComputacionalLabel.Text = 'AP02 - Atividade Computacional';
            app.HTML = uihtml(app.GridLayout);
            app.HTML.HTMLSource = '<hr style="height:2px;border-width:0;color:rgb(99,187,224);background-color:rgb(99,187,244)"></hr>';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.AlternativaaTab = uitab(app.TabGroup);
            app.AlternativaaTab.Title = 'Alternativa a';
            app.GridLayout2 = uigridlayout(app.AlternativaaTab);
            app.aCalculeaDFTeFFTsdexnusando32amostrasdosinalLabel = uilabel(app.GridLayout2);
            app.aCalculeaDFTeFFTsdexnusando32amostrasdosinalLabel.Text = 'a) Calcule a DFT e FFTs de x[n] usando 32 amostras do sinal.';
            app.alternativaASelectButtons = uibuttongroup(app.GridLayout2);
            app.alternativaASelectButtons.SelectionChangedFcn = createCallbackFcn(app, @alternativaASelectButtonsSelectionChanged, true);
            app.alternativaASelectButtons.Title = 'Selecione uma metodologia de cálculo para visualizar os gráficos e os coeficientes';
            app.TransformadaDiscretadeFourierDFTButton = uiradiobutton(app.alternativaASelectButtons);
            app.TransformadaDiscretadeFourierDFTButton.Text = 'Transformada Discreta de Fourier (DFT)';
            app.TransformadaDiscretadeFourierDFTButton.Value = true;
            app.TransformadaRpidadeFourierFFTDizimaonotempoButton = uiradiobutton(app.alternativaASelectButtons);
            app.TransformadaRpidadeFourierFFTDizimaonotempoButton.Text = 'Transformada Rápida de Fourier (FFT) - Dizimação no tempo';
            app.TransformadaRpidadeFourierFFTDizimaonafrequnciaButton = uiradiobutton(app.alternativaASelectButtons);
            app.TransformadaRpidadeFourierFFTDizimaonafrequnciaButton.Text = 'Transformada Rápida de Fourier (FFT) - Dizimação na frequência';
            app.alternativaACoeficientesPanel = uipanel(app.GridLayout2);
            app.alternativaACoeficientesPanel.Title = 'Coeficientes';
            app.UITableA = uitable(app.alternativaACoeficientesPanel);
            app.UITableA.ColumnName = {'k'; 'X[k]'};
            app.UITableA.RowName = {};
            app.GrficocomXknormalizadoPanel = uipanel(app.GridLayout2);
            app.GrficocomXknormalizadoPanel.Title = 'Gráfico (com X[k] normalizado)';
            app.UIAxesA = uiaxes(app.GrficocomXknormalizadoPanel);
            title(app.UIAxesA, '')
            xlabel(app.UIAxesA, 'k')
            ylabel(app.UIAxesA, '|X[k]|')
            app.AlternativabTab = uitab(app.TabGroup);
            app.AlternativabTab.Title = 'Alternativa b';
            app.GridLayout2_2 = uigridlayout(app.AlternativabTab);
            app.bCalculeaDFTeFFTsdexnusando32amostrasdosinalLabel = uilabel(app.GridLayout2_2);
            app.bCalculeaDFTeFFTsdexnusando32amostrasdosinalLabel.Text = {'b) Aumente o comprimento para 64 amostras, acrescentando 32 zeros às amostras de x[n], do item anterior, e calcule a '; 'DFT e FFTs do novo x[n].'};
            app.alternativaBSelectButtons = uibuttongroup(app.GridLayout2_2);
            app.alternativaBSelectButtons.SelectionChangedFcn = createCallbackFcn(app, @alternativaBSelectButtonsSelectionChanged, true);
            app.alternativaBSelectButtons.Title = 'Selecione uma metodologia de cálculo para visualizar os gráficos e os coeficientes';
            app.TransformadaDiscretadeFourierDFTButton_2 = uiradiobutton(app.alternativaBSelectButtons);
            app.TransformadaDiscretadeFourierDFTButton_2.Text = 'Transformada Discreta de Fourier (DFT)';
            app.TransformadaDiscretadeFourierDFTButton_2.Value = true;
            app.TransformadaRpidadeFourierFFTDizimaonotempoButton_2 = uiradiobutton(app.alternativaBSelectButtons);
            app.TransformadaRpidadeFourierFFTDizimaonotempoButton_2.Text = 'Transformada Rápida de Fourier (FFT) - Dizimação no tempo';
            app.TransformadaRpidadeFourierFFTDizimaonafrequnciaButton_2 = uiradiobutton(app.alternativaBSelectButtons);
            app.TransformadaRpidadeFourierFFTDizimaonafrequnciaButton_2.Text = 'Transformada Rápida de Fourier (FFT) - Dizimação na frequência';
            app.alternativaACoeficientesPanel_2 = uipanel(app.GridLayout2_2);
            app.alternativaACoeficientesPanel_2.Title = 'Coeficientes';
            app.UITableB = uitable(app.alternativaACoeficientesPanel_2);
            app.UITableB.ColumnName = {'k'; 'X[k]'};
            app.UITableB.RowName = {};
            app.GrficocomXknormalizadoPanel_2 = uipanel(app.GridLayout2_2);
            app.GrficocomXknormalizadoPanel_2.Title = 'Gráfico (com X[k] normalizado)';
            app.UIAxesB = uiaxes(app.GrficocomXknormalizadoPanel_2);
            title(app.UIAxesB, '')
            xlabel(app.UIAxesB, 'k')
            ylabel(app.UIAxesB, '|X[k]|')
            app.AlternativacTab = uitab(app.TabGroup);
            app.AlternativacTab.Title = 'Alternativa c';
            app.GridLayout2_3 = uigridlayout(app.AlternativacTab);
            app.bCalculeaDFTeFFTsdexnusando32amostrasdosinalLabel_2 = uilabel(app.GridLayout2_3);
            app.bCalculeaDFTeFFTsdexnusando32amostrasdosinalLabel_2.Text = 'c) Repita o processo dos itens (a) e (b) para o sinal x[n] com 64, 128, 256 e 512 amostras.';
            app.alternativaCSelectButtons = uibuttongroup(app.GridLayout2_3);
            app.alternativaCSelectButtons.SelectionChangedFcn = createCallbackFcn(app, @alternativaCSelectButtonsSelectionChanged, true);
            app.alternativaCSelectButtons.Title = 'Selecione uma metodologia de cálculo para visualizar os gráficos e os coeficientes';
            app.TransformadaDiscretadeFourierDFTButton_3 = uiradiobutton(app.alternativaCSelectButtons);
            app.TransformadaDiscretadeFourierDFTButton_3.Text = 'Transformada Discreta de Fourier (DFT)';
            app.TransformadaDiscretadeFourierDFTButton_3.Value = true;
            app.TransformadaRpidadeFourierFFTDizimaonotempoButton_3 = uiradiobutton(app.alternativaCSelectButtons);
            app.TransformadaRpidadeFourierFFTDizimaonotempoButton_3.Text = 'Transformada Rápida de Fourier (FFT) - Dizimação no tempo';
            app.TransformadaRpidadeFourierFFTDizimaonafrequnciaButton_3 = uiradiobutton(app.alternativaCSelectButtons);
            app.TransformadaRpidadeFourierFFTDizimaonafrequnciaButton_3.Text = 'Transformada Rápida de Fourier (FFT) - Dizimação na frequência';
            app.alternativaACoeficientesPanel_3 = uipanel(app.GridLayout2_3);
            app.alternativaACoeficientesPanel_3.Title = 'Coeficientes';
            app.TabGroup2 = uitabgroup(app.alternativaACoeficientesPanel_3);
            app.XamostNpontosTab_1 = uitab(app.TabGroup2);
            app.XamostNpontosTab_1.Title = 'X amost. - N pontos';
            app.UITableC_1 = uitable(app.XamostNpontosTab_1);
            app.UITableC_1.ColumnName = {'k'; 'X[k]'};
            app.UITableC_1.RowName = {};
            app.XamostNpontosTab_2 = uitab(app.TabGroup2);
            app.XamostNpontosTab_2.Title = 'X amost. - N pontos';
            app.UITableC_2 = uitable(app.XamostNpontosTab_2);
            app.UITableC_2.ColumnName = {'k'; 'X[k]'};
            app.UITableC_2.RowName = {};
            app.GrficocomXknormalizadoPanel_3 = uipanel(app.GridLayout2_3);
            app.GrficocomXknormalizadoPanel_3.Title = 'Gráfico (com X[k] normalizado)';
            app.TabGroup3 = uitabgroup(app.GrficocomXknormalizadoPanel_3);
            app.XamostrasNpontosTab_1 = uitab(app.TabGroup3);
            app.XamostrasNpontosTab_1.Title = 'X amostras - N pontos';
            app.UIAxesC_1 = uiaxes(app.XamostrasNpontosTab_1);
            title(app.UIAxesC_1, '')
            xlabel(app.UIAxesC_1, 'k')
            ylabel(app.UIAxesC_1, '|X[k]|')
            app.XamostrasNpontosTab_2 = uitab(app.TabGroup3);
            app.XamostrasNpontosTab_2.Title = 'X amostras - N pontos';
            app.UIAxesC_2 = uiaxes(app.XamostrasNpontosTab_2);
            title(app.UIAxesC_2, '')
            xlabel(app.UIAxesC_2, 'k')
            ylabel(app.UIAxesC_2, '|X[k]|')
            app.SelecaqntddeamostrasdesejadasPanel = uipanel(app.GridLayout2_3);
            app.SelecaqntddeamostrasdesejadasPanel.Title = 'Selec. a qntd de amostras desejadas';
            app.Slider = uislider(app.SelecaqntddeamostrasdesejadasPanel);
            app.Slider.Limits = [64 256];
            app.Slider.MajorTicks = [64 128 192 256];
            app.Slider.MajorTickLabels = {'64', '128', '256', '512'};
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = [];
            app.Slider.Value = 64;
            app.AlternativadTab = uitab(app.TabGroup);
            app.AlternativadTab.Title = 'Alternativa d';
            app.GridLayout2_4 = uigridlayout(app.AlternativadTab);
            app.bCalculeaDFTeFFTsdexnusando32amostrasdosinalLabel_3 = uilabel(app.GridLayout2_4);
            app.bCalculeaDFTeFFTsdexnusando32amostrasdosinalLabel_3.Text = {'d) Comente os resultados, mostrando numa tabela comparativa a quantidade de operações realizadas (somas e produ-'; 'tos).'};
            app.UITableD = uitable(app.GridLayout2_4);
            app.UITableD.ColumnName = {'N'; 'Somas DFT'; 'Somas FFT'; 'Produtos DFT'; 'Produtos FFT'};
            app.UITableD.RowName = {};
            app.AlternativaeTab = uitab(app.TabGroup);
            app.AlternativaeTab.Title = 'Alternativa e';
            app.GridLayout5 = uigridlayout(app.AlternativaeTab);
            app.UIAxesX_Original = uiaxes(app.GridLayout5);
            title(app.UIAxesX_Original, '')
            xlabel(app.UIAxesX_Original, 't')
            ylabel(app.UIAxesX_Original, 'x(t)')
            app.UIAxesX_Estimado = uiaxes(app.GridLayout5);
            title(app.UIAxesX_Estimado, '')
            xlabel(app.UIAxesX_Estimado, 't')
            ylabel(app.UIAxesX_Estimado, 'x(t) obtido pela IDFT')
            app.SelecioneaDFTquedesejaaplicaraiDFTeanalisarPanel = uipanel(app.GridLayout5);
            app.SelecioneaDFTquedesejaaplicaraiDFTeanalisarPanel.Title = 'Selecione a DFT que deseja aplicar a iDFT e analisar.';
            app.DropDown = uidropdown(app.SelecioneaDFTquedesejaaplicaraiDFTeanalisarPanel);
            app.DropDown.Items = {'x[n] com 32 amostras, DFT com 32 pontos', 'x[n] com 32 amostras, DFT com 64 pontos', 'x[n] com 64 amostras, DFT com 64 pontos', 'x[n] com 64 amostras, DFT com 128 pontos', 'x[n] com 128 amostras, DFT com 128 pontos', 'x[n] com 128 amostras, DFT com 256 pontos', 'x[n] com 256 amostras, DFT com 256 pontos', 'x[n] com 256 amostras, DFT com 512 pontos', 'x[n] com 512 amostras, DFT com 512 pontos', 'x[n] com 512 amostras, DFT com 1024 pontos'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'x[n] com 32 amostras, DFT com 32 pontos';
            app.bCalculeaDFTeFFTsdexnusando32amostrasdosinalLabel_4 = uilabel(app.GridLayout5);
            app.bCalculeaDFTeFFTsdexnusando32amostrasdosinalLabel_4.Text = 'e) Calcule a iDFTs para todos os DFTs realizados e apresente os sinais reconstruídos x(t) e compare com o sinal xc(t).';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end